classdef ASGBOS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        Names                          matlab.ui.control.Label
        Newtonlabel                    matlab.ui.control.Label
        GravitationalForceEditField    matlab.ui.control.NumericEditField
        GravitationalForceLabel        matlab.ui.control.Label
        AppthatSimulatesGravityBetweenObjectsinSpaceASGBOSLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        LeftPanel                      matlab.ui.container.Panel
        WhenenteringscientificnotationuselowercaseeEx5e1051010Label  matlab.ui.control.Label
        Mass1EditField                 matlab.ui.control.NumericEditField
        Mass1EditFieldLabel            matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        kgLabel_2                      matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        kgLabel                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        Mass2EditField                 matlab.ui.control.NumericEditField
        Mass2EditFieldLabel            matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        Background                     matlab.ui.control.Image
        HelpButton                     matlab.ui.control.Button
        AstronomicalBodyDropDown       matlab.ui.control.DropDown
        AstronomicalBodyDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % variable to store mass of seleceted dropdown
        Mass1value = 5.972e24; %stores value for minimum distance of each astronimical body (Default: Earth)
        Mindistance = 6471;%stores value for minimum distance of each astronimical body (Default: Earth)
        BodyName = 'Earth';% Stores the body name for the graph (Default: Earth)

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            int32 mass1val;
            int32 mindist;
            char bodyname;

           
        end

        % Value changed function: AstronomicalBodyDropDown, Mass1EditField
        function AstronomicalDropDownValueChanged(app, event)
            object = app.AstronomicalBodyDropDown.Value;
            
            % This sets the mass (kg) and minimum distance for each
            % selected planet in the dropdown menu
            switch object
                case 'Earth'
                 app.Background.ImageSource = imread('Earth.png');
                 mass1val= 5.972e24;
                 mindist= 6471;
                 bodyname= 'Earth';
                case 'Mercury'
                 app.Background.ImageSource = imread('Mercury.png'); 
                 mass1val= 3.285e23;
                 mindist= 2440;
                 bodyname= 'Mercury';
                case 'Venus'
                 app.Background.ImageSource = imread('Venus.png');  
                 mass1val= 4.867e24;
                 mindist= 6050;
                 bodyname= 'Venus';
                case 'Mars'
                 app.Background.ImageSource = imread('Mars.png');
                 mass1val= 6.390e23;
                 mindist= 3400;
                 bodyname= 'Mars';
                case 'Jupiter'
                 app.Background.ImageSource = imread('Jupiter.png');  
                 mass1val= 1.898e27;
                 mindist= 69911;
                 bodyname= 'Jupiter';
                case 'Saturn'
                 app.Background.ImageSource = imread('Saturn.png');  
                 mass1val= 5.683e26;
                 mindist= 58232;
                 bodyname= 'Saturn';
                case 'Uranus'
                 app.Background.ImageSource = imread('Uranus.png');  
                 mass1val= 8.681e25;
                 mindist= 25559;
                 bodyname= 'Uranus';
                case 'Neptune'
                 app.Background.ImageSource = imread('Neptune.png'); 
                 mass1val= 1.024e26;
                 mindist= 24622;
                 bodyname= 'Neptune';
                case 'Pluto'
                 app.Background.ImageSource = imread('Pluto.png'); 
                 mass1val= 1.309e22;
                 mindist= 2188;
                 bodyname= 'Pluto';
                case 'Sun'
                 app.Background.ImageSource = imread('Sun.png');
                 mass1val= 1.989e30;
                 mindist= 700000;
                 bodyname= 'Sun';

                 %Sagittarius is too big so it is removed
                %case 'Sagittarius A*'
                 %app.Background.ImageSource = imread('Sagittarius A.jpg');
                 %mass1val= 8.553e36;
            end
          
            %stores mass value in property so can use in other callbacks
            app.Mass1value = mass1val;
            %stores min distance values to use in other callback
            app.Mindistance= mindist;
            %displays value in Editfield for Mass 1
            app.Mass1EditField.Value = mass1val;
            %stores name of body picked
            app.BodyName= bodyname;

            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            M1= app.Mass1value;
            M2= app.Mass2EditField.Value;
            d= app.DistanceEditField.Value * 1000;
            G=6.67e-11;

            % Checking if inputs are legal
            if d < app.Mindistance * 1000
                uialert(app.UIFigure, ['The distance value is below the minimum allowed distance!' ...
                    ' Please check the table for the minimum distance allowed for each astronomical body.'], ...
                    'Input Error', 'Icon', 'error');
                return;
            end

            % Checks maximum distance
            if d> (app.Mindistance + 100000)*1000
                uialert(app.UIFigure, ['The distance is too large and will not fit on the graph. Please only do the minimum' ...
                    ' distance plus 100000 at most. Thank you.'],'Input Error', 'Icon', 'error');
                return;
            end
            
            % Gives error if User-given mass is or less than 0
            if M2 == 0 || M2 < 0
                uialert(app.UIFigure, ['Mass 2 has been left as 0 or input as an negative number. Please enter a value above 0' ...
                    ' to get accurate values.'],'Input Error', 'Icon', 'error');
                return;
            end


            %calculates specific distance user wanted
            Fgravspecific=(G*M1*M2)/d^2;
            app.GravitationalForceEditField.Value = Fgravspecific;

            %setting min and max distances for graph
            mindistance= app.Mindistance;
            maxdistance= mindistance + 100000;
            %making array distances
            distances= linspace(mindistance,maxdistance,100000) *1000;%*1000 converts km to m for calculation

            %calculates gravitational force for each distance
            Fgrav= (G*M1*M2)./distances.^2;

            %plots range of distances
            plot(app.UIAxes,distances/1000, Fgrav, 'DisplayName', app.BodyName);

            xlabel(app.UIAxes, 'Distance (km)');
            ylabel(app.UIAxes, 'Gravitational Force (N)');
            title(app.UIAxes, 'Gravitational Force vs. Distance');

            legend(app.UIAxes, 'show');
            
            %plot specific point asked for
            hold(app.UIAxes, 'on');
            h= plot(app.UIAxes, d/1000, Fgravspecific, 'ro', 'Markersize', 8);
            h.HandleVisibility= 'off';

            axis(app.UIAxes, 'tight');


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            HowToUse
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Resets the outputs to 0 and inputs to the default values (Earth)
            app.AstronomicalBodyDropDown.Value = 'Earth';
            app.Background.ImageSource = imread('Earth.png');
            app.Mass1EditField.Value= 5.972e24;
            app.Mass2EditField.Value= 0;
            app.DistanceEditField.Value=0;
            app.GravitationalForceEditField.Value= 0;
            cla(app.UIAxes, 'reset');
            xlabel(app.UIAxes, 'Distance (km)');
            ylabel(app.UIAxes, 'Gravitational Force (N)');
            title(app.UIAxes, 'Gravitational Force vs. Distance');
            legend(app.UIAxes, 'off');
        end

        % Image clicked function: Background
        function BackgroundImageClicked(app, event)
            object = app.AstronomicalBodyDropDown.Value;
            switch object
                case 'Earth'

                case 'Mercury'

                case 'Venus'

                case 'Mars'

                case 'Jupiter'

                case 'Saturn'

                case 'Uranus'
                 rick
                case 'Neptune'
                 
                case 'Pluto'
                 
                case 'Sun'
                 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.AstronomicalBodyDropDownLabel = uilabel(app.LeftPanel);
            app.AstronomicalBodyDropDownLabel.Text = 'Astronomical Body';
            app.AstronomicalBodyDropDown = uidropdown(app.LeftPanel);
            app.AstronomicalBodyDropDown.Items = {'Earth', 'Mercury', 'Venus', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto', 'Sun'};
            app.AstronomicalBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @AstronomicalDropDownValueChanged, true);
            app.AstronomicalBodyDropDown.Value = 'Earth';
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Background = uiimage(app.LeftPanel);
            app.Background.ImageClickedFcn = createCallbackFcn(app, @BackgroundImageClicked, true);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Earth.png');
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Mass2EditFieldLabel = uilabel(app.LeftPanel);
            app.Mass2EditFieldLabel.Text = 'Mass 2';
            app.Mass2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'For instructions on how to use the app as well as'; 'the minimum distances and average masses, please'; 'use the ''Help'' button below. '};
            app.kgLabel = uilabel(app.LeftPanel);
            app.kgLabel.Text = 'kg';
            app.kmLabel = uilabel(app.LeftPanel);
            app.kmLabel.Text = 'km';
            app.kgLabel_2 = uilabel(app.LeftPanel);
            app.kgLabel_2.Text = 'kg';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Mass1EditFieldLabel = uilabel(app.LeftPanel);
            app.Mass1EditFieldLabel.Text = 'Mass 1';
            app.Mass1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Mass1EditField.ValueChangedFcn = createCallbackFcn(app, @AstronomicalDropDownValueChanged, true);
            app.Mass1EditField.Editable = 'off';
            app.Mass1EditField.Value = 5.972e+24;
            app.WhenenteringscientificnotationuselowercaseeEx5e1051010Label = uilabel(app.LeftPanel);
            app.WhenenteringscientificnotationuselowercaseeEx5e1051010Label.Text = {'NOTE: When entering scientific notation'; 'use e or E instead of 10^'; 'Ex. 5e10 = 5*10^10'};
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gravitional Force vs Distance')
            xlabel(app.UIAxes, 'Distance (km)')
            ylabel(app.UIAxes, 'Gravitional Force (N)')
            zlabel(app.UIAxes, 'Z')
            app.AppthatSimulatesGravityBetweenObjectsinSpaceASGBOSLabel = uilabel(app.RightPanel);
            app.AppthatSimulatesGravityBetweenObjectsinSpaceASGBOSLabel.Text = {'App that Simulates Gravity Between Objects in Space'; '(ASGBOS) '};
            app.GravitationalForceLabel = uilabel(app.RightPanel);
            app.GravitationalForceLabel.Text = 'Gravitational Force';
            app.GravitationalForceEditField = uieditfield(app.RightPanel, 'numeric');
            app.GravitationalForceEditField.Editable = 'off';
            app.Newtonlabel = uilabel(app.RightPanel);
            app.Newtonlabel.Text = 'N';
            app.Names = uilabel(app.RightPanel);
            app.Names.Text = 'Seth Iris Canonigo, Nathan Huey, Lothar Escobar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASGBOS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end