classdef HowToUse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HelpTab                        matlab.ui.container.Tab
        Gif4                           matlab.ui.control.Image
        Gif3                           matlab.ui.control.Image
        Gif2                           matlab.ui.control.Image
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        Gif1                           matlab.ui.control.Image
        Instructions                   matlab.ui.control.Label
        HowtouseASGBOSLabel            matlab.ui.control.Label
        MinimumDistancesTab            matlab.ui.container.Tab
        AstronomicalBodyDropDownLabel  matlab.ui.control.Label
        MinimumDistanceEditField       matlab.ui.control.NumericEditField
        MinimumDistanceEditFieldLabel  matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        Background                     matlab.ui.control.Image
        AstronomicalBodyDropDown       matlab.ui.control.DropDown
        EarthLabel                     matlab.ui.control.Label
        MinimumDistancesLabel          matlab.ui.control.Label
        AverageMassesTab               matlab.ui.container.Tab
        MassoftheChicxulubasteroid461017kgLabel  matlab.ui.control.Label
        MassoftheMoon7341022Label      matlab.ui.control.Label
        MassofCeres911020kgLabel       matlab.ui.control.Label
        Averagemassofa1kmasteroid1311012kgLabel  matlab.ui.control.Label
        Averagemassofahuman62kgLabel   matlab.ui.control.Label
        AverageMassesLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        Steps = [1:4]; % An array of all of the help steps
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Steps = 1;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            step = app.Steps;
            
            % Increments the step number from the Next buttom
            if (step < 4)
                step = step + 1;

                % Checks what the step is and gives the appropriate
                % instructions
                switch step
                    case 1
                     app.Gif1.Visible ="on";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 1: Choose an astronomical body. \nChoosing a body will also give its mass');

                    case 2
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="on";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 2: Input another mass(kg) and \nthe distance between the two \nmasses(kg)\n(Use the minimum distance!)');
    
                    case 3
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="on";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 3: The gravitional force will be \noutputted on the right');
    
                    case 4
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="on";

                     app.Instructions.Text = sprintf('Step 4: The graph of the forces \nwill be outputted on the right');

                end
            app.Steps = step;
            end
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
           step = app.Steps;
            
            % Decrements the step number from the Next buttom
            if (step > 1)
                step = step - 1;

                switch step
                    case 1
                     app.Gif1.Visible ="on";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 1: Choose an astronomical body. \nChoosing a body will also give its mass');

                    case 2
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="on";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 2: Input another mass(kg) and \nthe distance between the two \nmasses(kg)\n(Use the minimum distance!)');
    
                    case 3
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="on";
                     app.Gif4.Visible ="off";

                     app.Instructions.Text = sprintf('Step 3: The gravitional force will be \noutputted on the right');
    
                    case 4
                     app.Gif1.Visible ="off";
                     app.Gif2.Visible ="off";
                     app.Gif3.Visible ="off";
                     app.Gif4.Visible ="on";

                end
            app.Steps = step;
            end


        end

        % Value changed function: AstronomicalBodyDropDown
        function AstronomicalBodyDropDownValueChanged(app, event)
            object = app.AstronomicalBodyDropDown.Value;
            

            % Outputs the minimum distances from the drop down menu
            switch object
                case 'Earth'
                 app.Background.ImageSource = imread('Earth.png');
                 
                 mindist= 6471;
                 
                case 'Mercury'
                 app.Background.ImageSource = imread('Mercury.png'); 
                 
                 mindist= 2440;
                 
                case 'Venus'
                 app.Background.ImageSource = imread('Venus.png');  
                 
                 mindist= 6050;
                 
                case 'Mars'
                 app.Background.ImageSource = imread('Mars.png');
                 
                 mindist= 3400;
                 
                case 'Jupiter'
                 app.Background.ImageSource = imread('Jupiter.png');  
                 
                 mindist= 69911;
                 
                case 'Saturn'
                 app.Background.ImageSource = imread('Saturn.png');  
                 
                 mindist= 58232;
                 
                case 'Uranus'
                 app.Background.ImageSource = imread('Uranus.png');  
                 
                 mindist= 25559;
                 
                case 'Neptune'
                 app.Background.ImageSource = imread('Neptune.png'); 
                 
                 mindist= 24622;
                 
                case 'Pluto'
                 app.Background.ImageSource = imread('Pluto.png'); 
                 
                 mindist= 2188;
                 
                case 'Sun'
                 app.Background.ImageSource = imread('Sun.png');
                 
                 mindist= 700000;
                 

            end

            app.MinimumDistanceEditField.Value = mindist;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HowtouseASGBOSLabel = uilabel(app.HelpTab);
            app.HowtouseASGBOSLabel.Text = 'How to use ASGBOS';
            app.Instructions = uilabel(app.HelpTab);
            app.Instructions.Text = {'Step 1: Choose an astronomical body. '; 'Choosing a body will also give its mass'};
            app.Gif1 = uiimage(app.HelpTab);
            app.Gif1.ImageSource = fullfile(pathToMLAPP, 'Step1.gif');
            app.PreviousButton = uibutton(app.HelpTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.HelpTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Gif2 = uiimage(app.HelpTab);
            app.Gif2.Visible = 'off';
            app.Gif2.ImageSource = fullfile(pathToMLAPP, 'Step2.gif');
            app.Gif3 = uiimage(app.HelpTab);
            app.Gif3.Visible = 'off';
            app.Gif3.ImageSource = fullfile(pathToMLAPP, 'Step3.gif');
            app.Gif4 = uiimage(app.HelpTab);
            app.Gif4.Visible = 'off';
            app.Gif4.ImageSource = fullfile(pathToMLAPP, 'Step4.gif');
            app.MinimumDistancesTab = uitab(app.TabGroup);
            app.MinimumDistancesTab.Title = 'Minimum Distances';
            app.MinimumDistancesLabel = uilabel(app.MinimumDistancesTab);
            app.MinimumDistancesLabel.Text = 'Minimum Distances';
            app.EarthLabel = uilabel(app.MinimumDistancesTab);
            app.EarthLabel.Text = {'Because the formula for Newton''s Law of Gravitation works for two center of masses, '; 'you also must take account the radius, or the distance from the core to the outermost'; 'atmosphere of the astronomical body.'};
            app.AstronomicalBodyDropDown = uidropdown(app.MinimumDistancesTab);
            app.AstronomicalBodyDropDown.Items = {'Earth', 'Mercury', 'Venus', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto', 'Sun'};
            app.AstronomicalBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @AstronomicalBodyDropDownValueChanged, true);
            app.AstronomicalBodyDropDown.Value = 'Earth';
            app.Background = uiimage(app.MinimumDistancesTab);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Earth.png');
            app.kmLabel = uilabel(app.MinimumDistancesTab);
            app.kmLabel.Text = 'km';
            app.MinimumDistanceEditFieldLabel = uilabel(app.MinimumDistancesTab);
            app.MinimumDistanceEditFieldLabel.Text = 'Minimum Distance';
            app.MinimumDistanceEditField = uieditfield(app.MinimumDistancesTab, 'numeric');
            app.MinimumDistanceEditField.Editable = 'off';
            app.MinimumDistanceEditField.Value = 6471;
            app.AstronomicalBodyDropDownLabel = uilabel(app.MinimumDistancesTab);
            app.AstronomicalBodyDropDownLabel.Text = 'Astronomical Body';
            app.AverageMassesTab = uitab(app.TabGroup);
            app.AverageMassesTab.Title = 'Average Masses';
            app.AverageMassesLabel = uilabel(app.AverageMassesTab);
            app.AverageMassesLabel.Text = 'Average Masses';
            app.Averagemassofahuman62kgLabel = uilabel(app.AverageMassesTab);
            app.Averagemassofahuman62kgLabel.Text = 'Average mass of a human: 62 kg';
            app.Averagemassofa1kmasteroid1311012kgLabel = uilabel(app.AverageMassesTab);
            app.Averagemassofa1kmasteroid1311012kgLabel.Text = 'Average mass of a 1 km asteroid: 1.31*10^12 kg';
            app.MassofCeres911020kgLabel = uilabel(app.AverageMassesTab);
            app.MassofCeres911020kgLabel.Text = 'Mass of Ceres: 9.1*10^20 kg';
            app.MassoftheMoon7341022Label = uilabel(app.AverageMassesTab);
            app.MassoftheMoon7341022Label.Text = 'Mass of the Moon: 7.34*10^22';
            app.MassoftheChicxulubasteroid461017kgLabel = uilabel(app.AverageMassesTab);
            app.MassoftheChicxulubasteroid461017kgLabel.Text = 'Mass of the Chicxulub asteroid: 4.6*10^17 kg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HowToUse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end